classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProjectNotesTransposerv21UIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        Image                     matlab.ui.control.Image
        FolderEditFieldLabel      matlab.ui.control.Label
        FolderEditField           matlab.ui.control.EditField
        FileNameEditFieldLabel    matlab.ui.control.Label
        FileNameEditField         matlab.ui.control.EditField
        AnalyzeButton             matlab.ui.control.Button
        ExtensionDropDownLabel    matlab.ui.control.Label
        ExtensionDropDown         matlab.ui.control.DropDown
        LoadButton                matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        FolderEditField_2Label    matlab.ui.control.Label
        FolderEditField_2         matlab.ui.control.EditField
        FileNameEditField_2Label  matlab.ui.control.Label
        FileNameEditField_2       matlab.ui.control.EditField
        ResultXMLfileLabel        matlab.ui.control.Label
        OpenWebTranslatorButton   matlab.ui.control.Button
        TransposeEditFieldLabel   matlab.ui.control.Label
        TransposeEditField        matlab.ui.control.NumericEditField
        MetrumSpinnerLabel        matlab.ui.control.Label
        MetrumSpinner             matlab.ui.control.Spinner
        Metrum_2SpinnerLabel      matlab.ui.control.Label
        Metrum_2Spinner           matlab.ui.control.Spinner
        KeysignatureListBoxLabel  matlab.ui.control.Label
        KeysignatureListBox       matlab.ui.control.ListBox
        NumberSliderLabel         matlab.ui.control.Label
        NumberSlider              matlab.ui.control.Slider
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if(app.FileNameEditField.Value ~= "")
                filePath=app.FileNameEditField.Value + "." + app.ExtensionDropDown.Value;
                if(app.FolderEditField.Value ~= "")
                    filePath = app.FolderEditField.Value + "/" + filePath;
                end 
                app.Image.ImageSource= filePath;
                app.LeftPanel.Title=filePath;
            end
            app.AnalyzeButton.Enable=true;
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            if(app.FileNameEditField_2.Value == "")
                resultPath="music.xml";
            else
                resultPath=app.FileNameEditField_2.Value + ".xml";
            end
            if(app.FolderEditField_2.Value ~= "")
                resultPath = app.FolderEditField.Value + "/" + resultPath;
            end 
           app.Image.ImageSource= Transpose(string(app.LeftPanel.Title), resultPath, [app.MetrumSpinner.Value,app.Metrum_2Spinner.Value], app.KeysignatureListBox.Value, app.NumberSlider.Value, app.TransposeEditField.Value);
        end

        % Button pushed function: OpenWebTranslatorButton
        function OpenWebTranslatorButtonPushed(app, event)
            web("https://www.soundslice.com/musicxml-viewer/",'-new', '-browser');
        end

        % Callback function
        function NumberSliderValueChanged(app, event)
            value = app.NumberSlider.Value;
            app.TransposeEditField.Value = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ProjectNotesTransposerv21UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {575, 575};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {769, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProjectNotesTransposerv21UIFigure = uifigure('Visible', 'off');
            app.ProjectNotesTransposerv21UIFigure.Name = 'Project: Notes Transposer v2.1';
            app.ProjectNotesTransposerv21UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ProjectNotesTransposerv21UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Image';
            app.Image = uiimage(app.LeftPanel);
            app.FolderEditFieldLabel = uilabel(app.LeftPanel);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.LeftPanel, 'text');
            app.FolderEditField.Value = '../Nuty';
            app.FileNameEditFieldLabel = uilabel(app.LeftPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.LeftPanel, 'text');
            app.FileNameEditField.Value = 'TestNew';
            app.AnalyzeButton = uibutton(app.LeftPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Enable = 'off';
            app.AnalyzeButton.Text = 'Analyze';
            app.ExtensionDropDownLabel = uilabel(app.LeftPanel);
            app.ExtensionDropDownLabel.Text = 'Extension';
            app.ExtensionDropDown = uidropdown(app.LeftPanel);
            app.ExtensionDropDown.Items = {'png', 'jpg'};
            app.ExtensionDropDown.Value = 'png';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Settings';
            app.FolderEditField_2Label = uilabel(app.RightPanel);
            app.FolderEditField_2Label.Text = 'Folder';
            app.FolderEditField_2 = uieditfield(app.RightPanel, 'text');
            app.FileNameEditField_2Label = uilabel(app.RightPanel);
            app.FileNameEditField_2Label.Text = 'File Name';
            app.FileNameEditField_2 = uieditfield(app.RightPanel, 'text');
            app.ResultXMLfileLabel = uilabel(app.RightPanel);
            app.ResultXMLfileLabel.Text = 'Result XML file';
            app.OpenWebTranslatorButton = uibutton(app.RightPanel, 'push');
            app.OpenWebTranslatorButton.ButtonPushedFcn = createCallbackFcn(app, @OpenWebTranslatorButtonPushed, true);
            app.OpenWebTranslatorButton.Text = 'Open Web Translator';
            app.TransposeEditFieldLabel = uilabel(app.RightPanel);
            app.TransposeEditFieldLabel.Text = 'Transpose';
            app.TransposeEditField = uieditfield(app.RightPanel, 'numeric');
            app.TransposeEditField.Limits = [-11 11];
            app.TransposeEditField.RoundFractionalValues = 'on';
            app.TransposeEditField.ValueDisplayFormat = '%.0f';
            app.MetrumSpinnerLabel = uilabel(app.RightPanel);
            app.MetrumSpinnerLabel.Text = 'Metrum';
            app.MetrumSpinner = uispinner(app.RightPanel);
            app.MetrumSpinner.Limits = [1 12];
            app.MetrumSpinner.RoundFractionalValues = 'on';
            app.MetrumSpinner.ValueDisplayFormat = '%.0f';
            app.MetrumSpinner.Value = 4;
            app.Metrum_2SpinnerLabel = uilabel(app.RightPanel);
            app.Metrum_2SpinnerLabel.Visible = 'off';
            app.Metrum_2SpinnerLabel.Text = 'Metrum_2';
            app.Metrum_2Spinner = uispinner(app.RightPanel);
            app.Metrum_2Spinner.Limits = [1 12];
            app.Metrum_2Spinner.RoundFractionalValues = 'on';
            app.Metrum_2Spinner.ValueDisplayFormat = '%.0f';
            app.Metrum_2Spinner.Value = 4;
            app.KeysignatureListBoxLabel = uilabel(app.RightPanel);
            app.KeysignatureListBoxLabel.Text = 'Key signature';
            app.KeysignatureListBox = uilistbox(app.RightPanel);
            app.KeysignatureListBox.Items = {'"none"', 'flat', 'sharp'};
            app.KeysignatureListBox.Value = '"none"';
            app.NumberSliderLabel = uilabel(app.RightPanel);
            app.NumberSliderLabel.Text = 'Number';
            app.NumberSlider = uislider(app.RightPanel);
            app.NumberSlider.Limits = [0 7];
            app.NumberSlider.MajorTicks = [0 1 2 3 4 5 6 7];
            app.NumberSlider.MinorTicks = [0 1 2 3 4 5 6 7];
            app.NumberSlider.Tooltip = {'Number of flats/sharps'};
            % Show the figure after all components are created
            app.ProjectNotesTransposerv21UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProjectNotesTransposerv21UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProjectNotesTransposerv21UIFigure)
        end
    end
end